classdef SwipeBotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SwipeBotAppUIFigure       matlab.ui.Figure
        HandSwitch                matlab.ui.control.ToggleSwitch
        LoadSwipeBotSystemButton  matlab.ui.control.Button
        CleanWindowButton         matlab.ui.control.Button
        Title                     matlab.ui.control.Label
        Subtitle                  matlab.ui.control.Label
        SafeCoLogo                matlab.ui.control.Image
        TestButton                matlab.ui.control.Button
        TextArea                  matlab.ui.control.TextArea
        MovetoHomeButton          matlab.ui.control.Button
        MovetoStoreButton         matlab.ui.control.Button
        StatusMessagesLabel       matlab.ui.control.Label
        HandSwitchLabel           matlab.ui.control.Label
        EMERGENCYSTOPButton       matlab.ui.control.Button
    end

    
    properties (Access = public)
        estop = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            assignin('base', "estop", app.estop);
        end

        % Button pushed function: LoadSwipeBotSystemButton
        function LoadSwipeBotSystemButtonPushed(app, event)
            clf                        % clear all windows
            clear all
            clc
            swipebot = SwipeBot;   % generate the SwipeBot
            assignin('base', "swipebot", swipebot);
        end

        % Button pushed function: CleanWindowButton
        function CleanWindowButtonPushed(app, event)
            swipebot = evalin("base","swipebot");
            swipebot.CleanWindow(app);  % start Window-Cleaning-Program
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            swipebot = evalin("base","swipebot");
            swipebot.Test(app);
            %app.InfoText.Value = "test";
            assignin('base', "estop", app.estop);
        end

        % Button pushed function: MovetoHomeButton
        function MovetoHomeButtonPushed(app, event)
            swipebot = evalin("base","swipebot");
            swipebot.MoveTo("home",app);  % move robots to home position
        end

        % Button pushed function: MovetoStoreButton
        function MovetoStoreButtonPushed(app, event)
            swipebot = evalin("base","swipebot");
            swipebot.MoveTo("store",app);  % move robots to store position
        end

        % Value changed function: HandSwitch
        function HandSwitchValueChanged(app, event)
            value = app.HandSwitch.Value;
            swipebot = evalin("base","swipebot");
            if value == "On"
                swipebot.PlotAndColourHand;
            end
            if value == "Off"
                delete(findobj('Tag',swipebot.hand.name));
            end
        end

        % Button pushed function: EMERGENCYSTOPButton
        function EMERGENCYSTOPButtonPushed(app, event)
            app.estop = 1;
            assignin('base', "estop", app.estop);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SwipeBotAppUIFigure = uifigure('Visible', 'off');
            app.SwipeBotAppUIFigure.Name = 'SwipeBotApp';
            app.HandSwitch = uiswitch(app.SwipeBotAppUIFigure, 'toggle');
            app.HandSwitch.ValueChangedFcn = createCallbackFcn(app, @HandSwitchValueChanged, true);
            app.LoadSwipeBotSystemButton = uibutton(app.SwipeBotAppUIFigure, 'push');
            app.LoadSwipeBotSystemButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSwipeBotSystemButtonPushed, true);
            app.LoadSwipeBotSystemButton.Text = 'Load SwipeBot-System';
            app.CleanWindowButton = uibutton(app.SwipeBotAppUIFigure, 'push');
            app.CleanWindowButton.ButtonPushedFcn = createCallbackFcn(app, @CleanWindowButtonPushed, true);
            app.CleanWindowButton.Text = 'Clean Window';
            app.Title = uilabel(app.SwipeBotAppUIFigure);
            app.Title.Text = 'SwipeBot';
            app.Subtitle = uilabel(app.SwipeBotAppUIFigure);
            app.Subtitle.Text = 'See the future clearer';
            app.SafeCoLogo = uiimage(app.SwipeBotAppUIFigure);
            app.SafeCoLogo.ImageSource = 'SafeCo Logo.png';
            app.TestButton = uibutton(app.SwipeBotAppUIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.TextArea = uitextarea(app.SwipeBotAppUIFigure);
            app.MovetoHomeButton = uibutton(app.SwipeBotAppUIFigure, 'push');
            app.MovetoHomeButton.ButtonPushedFcn = createCallbackFcn(app, @MovetoHomeButtonPushed, true);
            app.MovetoHomeButton.Text = {'Move to '; 'Home'};
            app.MovetoStoreButton = uibutton(app.SwipeBotAppUIFigure, 'push');
            app.MovetoStoreButton.ButtonPushedFcn = createCallbackFcn(app, @MovetoStoreButtonPushed, true);
            app.MovetoStoreButton.Text = {'Move to'; 'Store'};
            app.StatusMessagesLabel = uilabel(app.SwipeBotAppUIFigure);
            app.StatusMessagesLabel.Text = 'Status Messages:';
            app.HandSwitchLabel = uilabel(app.SwipeBotAppUIFigure);
            app.HandSwitchLabel.Text = 'Hand';
            app.EMERGENCYSTOPButton = uibutton(app.SwipeBotAppUIFigure, 'push');
            app.EMERGENCYSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @EMERGENCYSTOPButtonPushed, true);
            app.EMERGENCYSTOPButton.Text = 'EMERGENCY STOP';
            % Show the figure after all components are created
            app.SwipeBotAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SwipeBotApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SwipeBotAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SwipeBotAppUIFigure)
        end
    end
end